classdef InSink < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        SaveButton                    matlab.ui.control.Button
        LoadDataButton                matlab.ui.control.Button
        SensorsTable                  matlab.ui.control.Table
        SyncIMUstoeachotherPanel      matlab.ui.container.Panel
        SyncIMUStartButton            matlab.ui.control.Button
        CheckIMUSyncBeg               matlab.ui.control.Image
        SyncIMUEndButton              matlab.ui.control.Button
        CheckIMUSyncEnd               matlab.ui.control.Image
        CropIMUsButton                matlab.ui.control.Button
        CropForcesButton              matlab.ui.control.Button
        PlotOptionsPanel              matlab.ui.container.Panel
        AxesButtonGroup               matlab.ui.container.ButtonGroup
        XButton                       matlab.ui.control.RadioButton
        YButton                       matlab.ui.control.RadioButton
        ZButton                       matlab.ui.control.RadioButton
        MagnitudeButton               matlab.ui.control.RadioButton
        ViewAngularVelocityButton     matlab.ui.control.Button
        ViewLinearAccelerationButton  matlab.ui.control.Button
        SyncIMUstoForcesignalPanel    matlab.ui.container.Panel
        CheckGRFSyncEnd               matlab.ui.control.Image
        CheckGRFSyncBeg               matlab.ui.control.Image
        SyncGRFStartButton            matlab.ui.control.Button
        SyncGRFEndButton              matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        SetDirectoryButton            matlab.ui.control.Button
        CropBothSignals               matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        Label                         matlab.ui.control.Label
        StatusLabel                   matlab.ui.control.Label
        FileLabel                     matlab.ui.control.Label
        DataViewerPanel               matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        DirectoryLabel                matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        reset_data %is data currently loaded?
        directory %file directory
        file % imported filename
        loc %file path
        original %untouched original data
        data % the imported IMU/Force data for a subject
        plot_ax % which axes to plot for sensors
        segment % cell array of present sensors
        Fs % sensor sampling frequency
        lim_IMU %max length of all sensor signals
        min_lim_IMU % min length of all sensor signals
        signal % which signal to use for syncing (accel, gyro, magnitude of accel/gyro)
        
        %filtering
        fn % nyquist freq
        fc % filter cut-off (lowpass)
        b %butterworth coeff
        a %butterworth coeff
        
        %cropping trials
        trim_start %frame of new trial start
        trim_end %frame of new trial end
        sacrum_trim_start %frame of new trial start when syncing sacrum to vgrf
        sacrum_trim_end %frame of new trial end when syncing sacrum to vgrf
        grf_trim_start %frame of new grf trial start
        grf_trim_end %frame of new grf trial end
        
        %syncing start of signal
        indexesIMU % frames to shift each signal
        AlignedIMUData % IMU data synced
        intervalsIMU % interval where syncing was based off of
        align_time % time vector for synced signal
        IMUStartSynced % logical to make sure you synced beginning of trial
        
        %syncing end of trial
        AlignedIMUData2 % IMU data synced at end of trial
        intervalsIMU2 %interval where syncing (at end of trial) was based off of
        drifted % frames to shift each signal
        driftslope % rate of sensor clock drift (assumed constant)
        IMUEndSynced % logical to make sure you synced end of trial
        
        %syncing imu and grf
        IMUs_are_synced %when imu signals synced at start and end
        grf_down %downsampled grf signal
        time_down %downsampled grf time
        GRFStartSynced %logical to make sure you synced beginning of trial
        GRFEndSynced %logical to make sure you synced end of trial
    end
    
    methods (Access = private)
        
        function PlotSyncedData(app, synced_time, synced_signals, interval, signal)
            cla(app.UIAxes, 'reset')
            %             hold(app.UIAxes, 'on')
            grid(app.UIAxes, 'on')
            
            plot(app.UIAxes, synced_time, synced_signals);
            xlim(app.UIAxes, [synced_time(min(interval)), synced_time(max(interval))])
            
            %update axes/title
            if contains(signal, 'w')
                app.UIAxes.YLabel.String = 'Angular Velocity [deg/s]';
            else
                app.UIAxes.YLabel.String = 'Linear Acceleration [m/s^s]';
            end
            
            %             hold(app.UIAxes, 'off')
            
        end
        
        function ApplyFinalShift(app)
            % Indentify max minimum and min maximum for length of trials
            for seg = 1:length(app.segment)
                min_times(seg) = min(app.data.IMU.(app.segment{seg}).time_sd);
                max_times(seg) = max(app.data.IMU.(app.segment{seg}).time_sd);
            end
            % If a lot of data is missing, just interpolate signals so you
            % don't accidentally truncate good data
            extrap_done = false;
            for seg = 1:length(app.segment)
                if max_times(seg) < 0.75*max(max_times)  % if more than 3/4 is missing, extrapolate data
                    [~, max_seg] = max(max_times);
                    short_time_sd = app.data.IMU.(app.segment{seg}).time_sd;
                    long_time_sd = app.data.IMU.(app.segment{max_seg}).time_sd;
                    % extrapolate short signal time to length of longest signal
                    app.data.IMU.(app.segment{seg}).time_sd = ...
                        interp1(1:length(short_time_sd), short_time_sd, 1:length(long_time_sd), 'linear', 'extrap')';
                    app.data.IMU.(app.segment{seg}).time = ...
                        interp1(1:length(short_time_sd), app.data.IMU.(app.segment{seg}).time, 1:length(long_time_sd), 'linear', 'extrap')';
                    % extrapolate short signal data to length of longest
                    % signal, use 'nearest' extrapolation:
                    app.data.IMU.(app.segment{seg}).a = ...
                        interp1(1:length(short_time_sd), app.data.IMU.(app.segment{seg}).a, 1:length(long_time_sd), 'nearest', 'extrap');
                    app.data.IMU.(app.segment{seg}).w = ...
                        interp1(1:length(short_time_sd), app.data.IMU.(app.segment{seg}).w, 1:length(long_time_sd), 'nearest', 'extrap');
                    app.data.IMU.(app.segment{seg}).m = ...
                        interp1(1:length(short_time_sd), app.data.IMU.(app.segment{seg}).m, 1:length(long_time_sd), 'nearest', 'extrap');
                    % fix seg time_sd, time, a, w, m
                    extrap_done = true;
                else                  
                end
            end
            
            if extrap_done
                for seg = 1:length(app.segment)
                    min_times(seg) = min(app.data.IMU.(app.segment{seg}).time_sd);
                    max_times(seg) = max(app.data.IMU.(app.segment{seg}).time_sd);
                end
            end
            
            % Truncate all sensor data
            for seg= 1:length(app.segment)
                index_keep = app.data.IMU.(app.segment{seg}).time_sd > max(min_times) & ...
                    app.data.IMU.(app.segment{seg}).time_sd < min(max_times);
                app.data.IMU.(app.segment{seg}).time = app.data.IMU.(app.segment{seg}).time_sd(index_keep);
                app.data.IMU.(app.segment{seg}).a = app.data.IMU.(app.segment{seg}).a(index_keep,:); %leave those colons alone, Ryan.
                app.data.IMU.(app.segment{seg}).w = app.data.IMU.(app.segment{seg}).w(index_keep,:);
                app.data.IMU.(app.segment{seg}).m = app.data.IMU.(app.segment{seg}).m(index_keep,:);
            end
            
            % Use interpolation to fit all sensor data to the time points of the first
            % mounted sensor and replace NaNs with nearest value
            %also copies sensor 1 time and pastes to other sensors
            for seg = 1:length(app.segment)
                %interp to first sensor.
                app.data.IMU.(app.segment{seg}).a = interp1(app.data.IMU.(app.segment{seg}).time,...
                    app.data.IMU.(app.segment{seg}).a,app.data.IMU.(app.segment{1}).time,'nearest','extrap');
                app.data.IMU.(app.segment{seg}).w = interp1(app.data.IMU.(app.segment{seg}).time,...
                    app.data.IMU.(app.segment{seg}).w,app.data.IMU.(app.segment{1}).time,'nearest','extrap');
                app.data.IMU.(app.segment{seg}).m = interp1(app.data.IMU.(app.segment{seg}).time,...
                    app.data.IMU.(app.segment{seg}).m,app.data.IMU.(app.segment{1}).time,'nearest','extrap');
                app.data.IMU.(app.segment{seg}).time = app.data.IMU.(app.segment{1}).time;
                
                %repalce NaNs at ends and re-insert into a/w/m fields
                app.data.IMU.(app.segment{seg}).a = fillmissing(app.data.IMU.(app.segment{seg}).a,'nearest');
                app.data.IMU.(app.segment{seg}).w = fillmissing(app.data.IMU.(app.segment{seg}).w,'nearest');
                app.data.IMU.(app.segment{seg}).m = fillmissing(app.data.IMU.(app.segment{seg}).m,'nearest');
                %
            end
        end
        
        function RecalcPlotSyncedSignals(app)
            %initialize plot
            cla(app.UIAxes, 'reset')
            grid(app.UIAxes, 'on')
            hold(app.UIAxes, 'on')
            
            % Recalculate magnitue of angular velocity for plotting
            for seg = 1:length(app.segment)
                %data.IMU.(segment{seg}).amag2 = calcresultant(data.IMU.(segment{seg}).a);
                
                app.data.IMU.(app.segment{seg}).wmag = calcresultant(app.data.IMU.(app.segment{seg}).w);
                % Recalculate magnitue of resultant acceleration for plotting
                %or just use vertical acceleration
                %                 app.data.IMU.(app.segment{seg}).amag = app.data.IMU.(app.segment{seg}).a(:,2); % NOT MAGNITUDE. JUST VERTICAL
                app.data.IMU.(app.segment{seg}).amag = calcresultant(app.data.IMU.(app.segment{seg}).a);
                %filter
                app.data.IMU.(app.segment{seg}).amagf = filtfilt(app.b,app.a,app.data.IMU.(app.segment{seg}).amag);
                app.data.IMU.(app.segment{seg}).wmagf = filtfilt(app.b,app.a,app.data.IMU.(app.segment{seg}).wmag);
                app.data.IMU.(app.segment{seg}).af = filtfilt(app.b,app.a,app.data.IMU.(app.segment{seg}).a);
                % end
                
                % Zero time
                app.data.IMU.(app.segment{seg}).time = ...
                    app.data.IMU.(app.segment{seg}).time - app.data.IMU.(app.segment{seg}).time(1);
                
                %plot shifted data
                if contains(app.signal, 'w')
                    plot(app.UIAxes, app.data.IMU.(app.segment{seg}).time, app.data.IMU.(app.segment{seg}).wmagf)
                    app.UIAxes.Title.String = 'Synced Signals (Final)';
                    app.UIAxes.YLabel.String = 'Angular Velocity [deg/s]';
                else
                    plot(app.UIAxes, app.data.IMU.(app.segment{seg}).time, app.data.IMU.(app.segment{seg}).amagf)
                    app.UIAxes.Title.String = 'Synced Signals (Final)';
                    app.UIAxes.YLabel.String = 'Linear Acceleration [m/s^2]';
                end
            end
            legend(app.UIAxes, app.segment);
            hold(app.UIAxes, 'off');
            
            app.data.IMU.time = app.data.IMU.(app.segment{1}).time;
            
            
        end
        
        function PlotGRFSacrum(app)
            seg = char(app.segment(strcmpi(app.segment, 'sacrum')));
            cla(app.UIAxes, 'reset')
            hold(app.UIAxes, 'on')
            grid(app.UIAxes, 'on')
            plot(app.UIAxes, app.data.time, app.data.force(:,3))
            plot(app.UIAxes, app.data.IMU.(seg).time, app.data.IMU.(seg).af(:,2)*70) %temp mass.
            legend(app.UIAxes, {'vGRF', 'Sacrum'});
            title(app.UIAxes, 'vGRF vs Vertical Acceleration*70kg');
            set(app.CheckGRFSyncBeg, 'visible', 'off'); %hide green check mark
            set(app.CheckGRFSyncEnd, 'visible', 'off'); %hide green check mark
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.reset_data = false;
            set(app.AxesButtonGroup, 'visible', 'off')
            addpath("..")
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {580, 580};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {217, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            if app.reset_data ~= true
                [app.file, app.loc, ~] = uigetfile(app.directory, 'MultiSelect','off');
                app.data = load([app.loc app.file]);
                %             app.file = 'Baseline_190901_RA.mat';
                app.original = load(fullfile(app.loc, app.file));
                app.reset_data = false;
            end
            app.data = app.original.data;
            addpath('APP_Support');
            
            %% determine which sensors are present
            possible_sensors = {'Rshank','Lshank','Rthigh','Lthigh','Sacrum','sacrum'};
            app.segment = findsensors(app.data.IMU, possible_sensors);
            
            %% calculate magnitude of angular velocity and set aside
            
            sensor_len = NaN(length(app.segment),1);
            
            for seg = 1:length(app.segment)
                %set aside vertical acceleration for each sensor
                %                 app.data.IMU.(app.segment{seg}).amag = app.data.IMU.(app.segment{seg}).a(:,2); % NOT ACTUAL MAGNITUDE OF ACCEL, JUST VERTICAL ACCEL.
                app.data.IMU.(app.segment{seg}).amag = calcresultant(app.data.IMU.(app.segment{seg}).a);
                
                
                % Calculate magnitude of angular velocity for each sensor
                app.data.IMU.(app.segment{seg}).wmag = calcresultant(app.data.IMU.(app.segment{seg}).w);
                %         data.IMU.(segment{seg}).wmag = (data.IMU.(segment{seg}).w(:,1).^2 +...
                %             data.IMU.(segment{seg}).w(:,2).^2 + data.IMU.(segment{seg}).w(:,3).^2).^(1/2);
                sensor_len(seg) = length(app.data.IMU.(app.segment{seg}).amag);
                
            end
            
            %Apply filter to amag/wmag for better syncing
            app.fn = app.data.IMU.Fs/2;
            app.fc = 15;
            [app.b,app.a] = butter(2, app.fc/app.fn);
            for seg = 1:length(app.segment)
                app.data.IMU.(app.segment{seg}).amagf = filtfilt(app.b,app.a,app.data.IMU.(app.segment{seg}).amag);
                app.data.IMU.(app.segment{seg}).wmagf = filtfilt(app.b,app.a,app.data.IMU.(app.segment{seg}).wmag);
                %and a filtered acceleration signal while we're at it
                app.data.IMU.(app.segment{seg}).af = filtfilt(app.b, app.a, app.data.IMU.(app.segment{seg}).a);
                
            end
            
            
            %initialize some variables
            app.Fs = 500; % round(1/mean(diff(app.data.IMU.(app.segment{1}).time)));
            app.lim_IMU = max(sensor_len);
            app.min_lim_IMU = min(sensor_len);
            app.IMUStartSynced = false;
            app.IMUEndSynced = false;
            %downsample forces and time vector
            app.grf_down = downsample(app.data.force, app.data.Fs/app.data.IMU.Fs);
            app.time_down = linspace(0, length(app.grf_down)/app.data.IMU.Fs, length(app.grf_down))';
            
            %redefine time vectors for IMUs
            for seg = 1:length(app.segment)
                app.data.IMU.(app.segment{seg}).time = ...
                    linspace(0, ...
                    length(app.data.IMU.(app.segment{seg}).time)/app.Fs, ...
                    length(app.data.IMU.(app.segment{seg}).time))';
            end
            
            %update table with sensor names
            app.SensorsTable.Data  = app.segment';
            %show plot to give some more visual feedback
            ViewAngularVelocityButtonPushed(app, event)
            %update status
            app.FileLabel.Text = ['File Loaded: ' app.file];
            %set IMUs/GRF status to not synced
            app.IMUs_are_synced = false;
            app.IMUStartSynced = false;
            app.IMUEndSynced = false;
            app.GRFStartSynced = false;
            app.GRFEndSynced = false;
            %enable buttons
            set(app.ViewAngularVelocityButton, 'enable', 'on')
            set(app.ViewLinearAccelerationButton, 'enable', 'on')
            set(app.SyncIMUStartButton, 'enable', 'on')
            set(app.CropIMUsButton, 'enable', 'on')
            set(app.CropForcesButton, 'enable', 'off')
            %disable/enable check marks and buttons
            set(app.CheckIMUSyncBeg, 'visible', 'off')
            set(app.CheckIMUSyncEnd, 'visible', 'off')
            set(app.SyncIMUEndButton, 'enable', 'off')
            set(app.SyncGRFStartButton, 'enable', 'off')
            set(app.SyncGRFEndButton, 'enable', 'off')
            set(app.CheckGRFSyncBeg, 'visible', 'off')
            set(app.CheckGRFSyncEnd, 'visible', 'off')
            set(app.SaveButton, 'enable', 'off')
            set(app.ResetButton, 'enable', 'on')
            set(app.AxesButtonGroup, 'visible', 'on')
            
        end

        % Button pushed function: ViewAngularVelocityButton
        function ViewAngularVelocityButtonPushed(app, event)
            data = app.data;
            cla(app.UIAxes, 'reset')
            hold(app.UIAxes, 'on')
            grid(app.UIAxes, 'on')
            
            %pre-sync is same as post end-sync plotting (just wanna view):
            if ~app.IMUStartSynced || app.IMUEndSynced
                %determine if plotting axis-specific or magnitude
                if isempty(app.plot_ax)
                    app.plot_ax = 0;
                end
                %plot magnitude or axis-specific data
                if app.plot_ax < 1
                    app.signal = 'wmagf';
                    for i = 1:length(app.segment)
                        plot(app.UIAxes, data.IMU.(app.segment{i}).time, data.IMU.(app.segment{i}).wmagf)
                    end
                else
                    app.signal = 'w';
                    for i = 1:length(app.segment)
                        plot(app.UIAxes, data.IMU.(app.segment{i}).time, data.IMU.(app.segment{i}).w(:,app.plot_ax))
                    end
                end
                
                % post-begin sync plotting:
            else
                %initialize y axis data (one column for each sensor)
                data_to_align = NaN(app.lim_IMU,length(app.segment));
                aligned_data = NaN(app.lim_IMU,length(app.segment));


                if app.plot_ax < 1
                    app.signal = 'wmagf';
                    %put all sensors into array so its padded with NaNs
                    for s = 1:length(app.segment)
                        data_to_align(1:length(data.IMU.(app.segment{s}).(app.signal)), s) = ...
                            data.IMU.(app.segment{s}).(app.signal);
                    end
                    %apply time shift
                    for i = 1:length(app.segment)
                        if app.indexesIMU(i) >= 0
                            shift = app.indexesIMU(i);
                            ind_orig = shift + 1:app.lim_IMU;
                            ind_shift = 1:app.lim_IMU - shift;
                        else app.indexesIMU(i) < 0;
                            shift = app.indexesIMU(i);
                            ind_orig = 1:app.lim_IMU + shift;
                            ind_shift = -shift + 1:app.lim_IMU;
                        end
                        aligned_data(ind_shift,i) = data_to_align(ind_orig,i);
                        app.AlignedIMUData(:,i) = aligned_data(:,i); %kicks this out to app workspace
                        %because sync End is looking for it. Will now
                        %update depending on what signal/axis is currently
                        %plotted!
                        plot(app.UIAxes, app.align_time, app.AlignedIMUData(:,i));
                    end
                else
                    app.signal = 'w';
                    %put all sensors into array so its padded with NaNs
                    for s = 1:length(app.segment)
                        data_to_align(1:length(data.IMU.(app.segment{s}).(app.signal)), s) = ...
                            data.IMU.(app.segment{s}).(app.signal)(:,app.plot_ax);
                    end
                    %apply time shift
                    for i = 1:length(app.segment)
                        if app.indexesIMU(i) >= 0
                            shift = app.indexesIMU(i);
                            ind_orig = shift + 1:app.lim_IMU;
                            ind_shift = 1:app.lim_IMU - shift;
                        else app.indexesIMU(i) < 0;
                            shift = app.indexesIMU(i);
                            ind_orig = 1:app.lim_IMU + shift;
                            ind_shift = -shift + 1:app.lim_IMU;
                        end
                        aligned_data(ind_shift,i) = data_to_align(ind_orig,i);
                        app.AlignedIMUData(:,i) = aligned_data(:,i);
                        plot(app.UIAxes, app.align_time, app.AlignedIMUData(:,i));
                    end
                end
            end
            
            hold(app.UIAxes, 'off')
            legend(app.UIAxes, app.segment)
            app.UIAxes.Title.String = 'Angular Velocity';
            app.UIAxes.YLabel.String = 'Angular Velocity [deg/s]';
        end

        % Button pushed function: ViewLinearAccelerationButton
        function ViewLinearAccelerationButtonPushed(app, event)
            data = app.data;
            cla(app.UIAxes, 'reset')
            hold(app.UIAxes, 'on')
            grid(app.UIAxes, 'on')
            %pre-sync plotting
            if ~app.IMUStartSynced || app.IMUEndSynced
                %determine if plotting axis-specific or magnitude
                if isempty(app.plot_ax)
                    app.plot_ax = 0;
                end
                %plot magnitude or axis-specific data
                if app.plot_ax < 1
                    app.signal = 'amagf';
                    for i = 1:length(app.segment)
                        plot(app.UIAxes, data.IMU.(app.segment{i}).time, data.IMU.(app.segment{i}).amagf)
                    end
                else
                    app.signal = 'a';
                    for i = 1:length(app.segment)
                        plot(app.UIAxes, data.IMU.(app.segment{i}).time, data.IMU.(app.segment{i}).a(:,app.plot_ax))
                    end
                end
                %post-begin sync plotting:
            else
                %initialize y axis data (one column for each sensor)
                data_to_align = NaN(app.lim_IMU,length(app.segment));
                aligned_data = NaN(app.lim_IMU,length(app.segment));
                
                if app.plot_ax < 1
                    app.signal = 'amagf';
                    %put all sensors into array so its padded with NaNs
                    for s = 1:length(app.segment)
                        data_to_align(1:length(data.IMU.(app.segment{s}).(app.signal)), s) = ...
                            data.IMU.(app.segment{s}).(app.signal);
                    end
                    %apply time shift
                    for i = 1:length(app.segment)
                        if app.indexesIMU(i) >= 0
                            shift = app.indexesIMU(i);
                            ind_orig = shift + 1:app.lim_IMU;
                            ind_shift = 1:app.lim_IMU - shift;
                        else app.indexesIMU(i) < 0;
                            shift = app.indexesIMU(i);
                            ind_orig = 1:app.lim_IMU + shift;
                            ind_shift = -shift + 1:app.lim_IMU;
                        end
                        aligned_data(ind_shift,i) = data_to_align(ind_orig,i);
                        plot(app.UIAxes, app.align_time, aligned_data(:,i));
                    end
                else
                    app.signal = 'a';
                    %put all sensors into array so its padded with NaNs
                    for s = 1:length(app.segment)
                        data_to_align(1:length(data.IMU.(app.segment{s}).(app.signal)), s) = ...
                            data.IMU.(app.segment{s}).(app.signal)(:,app.plot_ax);
                    end
                    %apply time shift
                    for i = 1:length(app.segment)
                        if app.indexesIMU(i) >= 0
                            shift = app.indexesIMU(i);
                            ind_orig = shift + 1:app.lim_IMU;
                            ind_shift = 1:app.lim_IMU - shift;
                        else app.indexesIMU(i) < 0;
                            shift = app.indexesIMU(i);
                            ind_orig = 1:app.lim_IMU + shift;
                            ind_shift = -shift + 1:app.lim_IMU;
                        end
                        aligned_data(ind_shift,i) = data_to_align(ind_orig,i);
                        plot(app.UIAxes, app.align_time, aligned_data(:,i));
                    end
                    
                end
            end
            
            hold(app.UIAxes, 'off')
            legend(app.UIAxes, app.segment)
            app.UIAxes.Title.String = 'Linear Acceleration';
            app.UIAxes.YLabel.String = 'Linear Acceleration [m/s^2]';
            
        end

        % Selection changed function: AxesButtonGroup
        function AxesButtonGroupSelectionChanged(app, event)
            selectedButton = app.AxesButtonGroup.SelectedObject;
            if selectedButton == app.XButton
                app.plot_ax = 1;
            elseif selectedButton == app.YButton
                app.plot_ax = 2;
            elseif selectedButton == app.ZButton
                app.plot_ax = 3;
            elseif selectedButton == app.MagnitudeButton
                app.plot_ax = 0;
            end
            if contains(app.signal, 'w')
                ViewAngularVelocityButtonPushed(app, event)
            else
                ViewLinearAccelerationButtonPushed(app, event)
            end
        end

        % Button pushed function: SyncIMUStartButton
        function SyncIMUStartButtonPushed(app, event)
            sensor_names = app.segment;
            IMU_data = app.data.IMU;
            time_field = 'time';
            lim = app.lim_IMU;
            signal = app.signal;
            % initialize variables
            data_to_align = NaN(lim, length(sensor_names));
            
            % organize all sensors into a single matrix for corr_shift()
            for s = 1:length(sensor_names)
                data_to_align(1:length(IMU_data.(sensor_names{s}).(signal)), s) = ...
                    IMU_data.(sensor_names{s}).(signal);
                % keep just time vector of longest trial
                if lim == length(IMU_data.(sensor_names{s}).(time_field))
                    app.align_time = IMU_data.(sensor_names{s}).(time_field);
                end
            end
            
            %select interval containing part of signal to base sync off of:
            timex = app.UIAxes.XAxis.Limits;
            timex(timex < 0) = 0;
            timex(timex > app.min_lim_IMU/app.Fs) = app.min_lim_IMU/app.Fs;
            %update status label on bottom of gui
            app.StatusLabel.Text = ['Region boundaries: ', num2str(timex(1)) ' - ' num2str(timex(2)) ' seconds. Syncing signals...'];
            %figure out corresponding frames
            align_temp = IMU_data.(sensor_names{1}).(time_field) > min(timex) & ...
                IMU_data.(sensor_names{1}).(time_field) < max(timex);
            % beginning of spike
            align_start = find(IMU_data.(sensor_names{1}).(time_field) == ...
                min(IMU_data.(sensor_names{1}).(time_field)(align_temp)));
            % end of spike
            align_end = find(IMU_data.(sensor_names{1}).(time_field) == ...
                max(IMU_data.(sensor_names{1}).(time_field)(align_temp)));
            
            % Pass everything on to corr_shift():
            r = find(contains(sensor_names,app.segment{1}));
            % shift signals forwards/backwards in regards to first sensor in app.segment signal ("reference signal")
            [app.indexesIMU,app.AlignedIMUData] = corr_shift(data_to_align(:,r), data_to_align, align_start, align_end, 'f');
            app.intervalsIMU = [align_start align_end];
            
            time_shift = round(app.indexesIMU/app.Fs,3);
            for i = 2:length(app.segment)
                shift_text{i-1} = num2str(time_shift(i));
            end
            app.StatusLabel.Text = ...
                ['Sensors shifted by ' strjoin(shift_text, ', ') ' sec relative to ' app.segment{1}];
            PlotSyncedData(app, app.align_time, app.AlignedIMUData, app.intervalsIMU, app.signal);
            app.UIAxes.Title.String = 'Synced Signals (Beginning)';
            legend(app.UIAxes, app.segment)
            
            
            %confirm sync with user before we apply it to time vector
            opt = {'Yes, looks good!', 'No, I will try again.'};
            fig = uifigure('Position', [500, 500, 400, 200]);
            selection = uiconfirm(fig, 'Take a look at the plot. Do you want to apply this time shift?',...
                'Confirm Synchronization', 'Options', opt, 'CancelOption', 2);
            close(fig)
            
            if strcmp(selection, opt(1)) %selected YES
                %% apply shift to time
                for seg = 1:length(app.segment)
                    %apply shift from first hop
                    app.data.IMU.(app.segment{seg}).time_shifted = ...
                        app.data.IMU.(app.segment{seg}).time - app.indexesIMU(seg)/app.Fs;
                end
                app.UIAxes.XLimMode = 'auto'; %return plot to full view
                app.IMUStartSynced = true; %confirm beginning is synced
                set(app.CheckIMUSyncBeg, 'visible', 'on'); %show green check mark
                set(app.SyncIMUEndButton, 'enable', 'on'); %enable sync end of file
                set(app.SyncIMUStartButton, 'enable', 'off');
                app.IMUs_are_synced = true;
            else
                app.StatusLabel.Text = '';
                if contains(app.signal, 'w')
                    ViewAngularVelocityButtonPushed(app, event)
                else
                    ViewLinearAccelerationButtonPushed(app, event)
                end
                
            end
            
            
        end

        % Button pushed function: SyncIMUEndButton
        function SyncIMUEndButtonPushed(app, event)
            %check if beginning has been synced yet
            if ~app.IMUStartSynced
                errordlg('Beginning of signals must be synced first, then end.', 'Synchronization Error')
                return
            end
            
            lim = length(app.align_time);
            %select interval containing part of signal to base sync off of:
            timex = app.UIAxes.XAxis.Limits;
            timex(timex < 0) = 0;
            timex(timex > lim/app.Fs) = (lim-1)/app.Fs;
            % get corresponding frames
            align_temp = app.data.IMU.(app.segment{1}).time > timex(1) & app.data.IMU.(app.segment{1}).time < timex(2);
            align_s = find(app.data.IMU.(app.segment{1}).time == min(app.data.IMU.(app.segment{1}).time(align_temp)));
            align_e = find(app.data.IMU.(app.segment{1}).time == max(app.data.IMU.(app.segment{1}).time(align_temp)));
            
            %compare to first sensor, calcualte drift in clocks over duration of trial.
            [app.drifted, app.AlignedIMUData2] = corr_shift(app.AlignedIMUData(:,1), app.AlignedIMUData, align_s, align_e, 'f');
            app.intervalsIMU2 = [align_s align_e];
            
            %calc time shift at end of trial
            time_shift = round(app.drifted/app.Fs,3);
            for i = 2:length(app.segment)
                shift_text{i-1} = num2str(time_shift(i));
            end
            app.StatusLabel.Text = ...
                ['Sensors shifted by ' strjoin(shift_text, ', ') ' sec relative to ' app.segment{1}];
            PlotSyncedData(app, app.align_time, app.AlignedIMUData2, app.intervalsIMU2, app.signal);
            app.UIAxes.Title.String = 'Synced Signals (End)';
            legend(app.UIAxes, app.segment)
            
            %confirm sync with user before we apply it to time vector
            opt = {'Yes, looks good!', 'No, I will try again.',};
            fig = uifigure('Position', [500, 500, 400, 200]);
            selection = uiconfirm(fig, 'Take a look at the plot. Do you want to apply this time shift?',...
                'Confirm Synchronization', 'Options', opt, 'CancelOption', 2);
            close(fig)
            if strcmp(selection, opt(1))
                %% apply shift to time
                for seg = 1:length(app.segment)
                    %calculate drift in IMU clock:
                    app.driftslope.(app.segment{seg}) = 1 - (app.drifted(seg)/app.Fs)/ ...
                        ((app.intervalsIMU2(2)-app.intervalsIMU(2))/app.Fs); %BEST. Slope between 2 hops/swivels.
                    %apply dritf correct from second hop
                    app.data.IMU.(app.segment{seg}).time_sd = app.driftslope.(app.segment{seg})* ...
                        app.data.IMU.(app.segment{seg}).time_shifted; %apply clock drift
                end
                
                %apply shift to time and interpolate
                if app.IMUStartSynced
                    ApplyFinalShift(app)
                    RecalcPlotSyncedSignals(app)
                else
                    errordlg('Beginning of signals must be synced first, then end.', 'Synchronization Error')
                end
                
                app.UIAxes.XLimMode = 'auto'; %return plot to full view
                set(app.CheckIMUSyncEnd, 'visible', 'on'); %show green check mark
                app.IMUEndSynced = true; %confirm beginning is synced
                set(app.SaveButton, 'enable', 'on') % enable save button
                %move on to syncing GRF
                set(app.SyncGRFStartButton, 'enable', 'on')
                set(app.CropForcesButton, 'enable', 'on')
                PlotGRFSacrum(app)
                set(app.ViewAngularVelocityButton, 'enable', 'off')
                set(app.ViewLinearAccelerationButton, 'enable', 'off')
                set(app.SyncIMUStartButton, 'enable', 'off')
                set(app.SyncIMUEndButton, 'enable', 'off')
                set(app.AxesButtonGroup, 'visible', 'off')
                % only crop both IMU/GRF after IMUs synced:
                set(app.CropBothSignals, 'enable', 'on') 
                set(app.CropIMUsButton, 'enable', 'off')
                set(app.CropForcesButton, 'enable', 'off')
            else
                app.StatusLabel.Text = '';
                PlotSyncedData(app, app.align_time, app.AlignedIMUData, app.intervalsIMU, app.signal);
                app.UIAxes.XLimMode = 'auto'; %return plot to full view
            end
        end

        % Button pushed function: CropIMUsButton
        function CropIMUsButtonPushed(app, event)
            addpath('APP_Support\');
            if ~app.IMUs_are_synced
                sensor_len = NaN(length(app.segment),1);
                for s = 1:length(app.segment)
                    if app.plot_ax < 1
                        plot_ax = 1;
                    else
                        plot_ax = app.plot_ax;
                    end
                    [~, app.trim_start(s,1), app.trim_end(s,1)] = ...
                        TrimTrial(app.data.IMU.(app.segment{s}).(app.signal)(:,plot_ax), app.data.IMU.(app.segment{s}).time);
                    sensor_fields = fieldnames(app.data.IMU.(app.segment{s}));
                    for f = 1:length(sensor_fields)
                        app.data.IMU.(app.segment{s}).(sensor_fields{f}) = ...
                            app.data.IMU.(app.segment{s}).(sensor_fields{f})(app.trim_start(s,1):app.trim_end(s,1),:);
                    end
                    %fix time vectors
                    app.data.IMU.(app.segment{s}).time = app.data.IMU.(app.segment{s}).time - app.data.IMU.(app.segment{s}).time(1);
                    sensor_len(s) = length(app.data.IMU.(app.segment{s}).time);
                end
                if contains(app.signal, 'w')
                    ViewAngularVelocityButtonPushed(app, event)
                else
                    ViewLinearAccelerationButtonPushed(app, event)
                end
                app.lim_IMU = max(sensor_len);
                app.min_lim_IMU = min(sensor_len);
                
            else 
            end
        end

        % Button pushed function: CropForcesButton
        function CropForcesButtonPushed(app, event)
            %get trim frames
            [~, app.grf_trim_start, app.grf_trim_end] = ...
                TrimTrial(app.data.force(:,3), app.data.time);
            %apply trim to all axes
            app.data.force = app.data.force(app.grf_trim_start:app.grf_trim_end, :);
            %fix time vectors
            app.data.time = app.data.time(app.grf_trim_start:app.grf_trim_end);
            app.data.time = linspace(0, length(app.data.force)/app.data.Fs, length(app.data.force))';
            %downsample forces and time vector
            app.grf_down = downsample(app.data.force, app.data.Fs/app.data.IMU.Fs);
            app.time_down = linspace(0, length(app.grf_down)/app.data.IMU.Fs, length(app.grf_down))';
            
            PlotGRFSacrum(app)
        end

        % Button pushed function: CropBothSignals
        function CropBothSignalsButtonPushed(app, event)
            %imus are synced to each other already
            %% Trim GRF First
            %get trim frames
            [~, app.grf_trim_start, app.grf_trim_end] = ...
                TrimTrial(app.data.force(:,3), app.data.time);
            %apply trim to all axes
            app.data.force = app.data.force(app.grf_trim_start:app.grf_trim_end, :);
            %fix time vectors
            app.data.time = app.data.time(app.grf_trim_start:app.grf_trim_end);
            app.data.time = linspace(0, length(app.data.force)/app.data.Fs, length(app.data.force))';
            %downsample forces and time vector
            app.grf_down = downsample(app.data.force, app.data.Fs/app.data.IMU.Fs);
            app.time_down = linspace(0, length(app.grf_down)/app.data.IMU.Fs, length(app.grf_down))';
            
            PlotGRFSacrum(app)
            
            %% Trim IMUs next
            %trim sacrum only and apply to all other sensors
            seg = char(app.segment(strcmpi(app.segment, 'sacrum')));
            app.signal = 'af';
            [~, app.sacrum_trim_start, app.sacrum_trim_end] = ...
                TrimTrial(app.data.IMU.(seg).(app.signal)(:,2), app.data.IMU.(seg).time);
            app.data.IMU.(seg).af = app.data.IMU.(seg).(app.signal)(app.sacrum_trim_start: app.sacrum_trim_end,:);  %kg estimated GRF
            
            app.data.IMU_len = length(app.data.IMU.(seg).af);
            
            %apply sacrum trim to all sensors
            for s = 1:length(app.segment)
                %apply to time, a, w, and m
                app.data.IMU.(app.segment{s}).time = linspace(0, app.data.IMU_len/app.data.IMU.Fs, app.data.IMU_len)';
                app.data.IMU.(app.segment{s}).a = app.data.IMU.(app.segment{s}).a(app.sacrum_trim_start: app.sacrum_trim_end,:);
                app.data.IMU.(app.segment{s}).w = app.data.IMU.(app.segment{s}).w(app.sacrum_trim_start: app.sacrum_trim_end,:);
                app.data.IMU.(app.segment{s}).m = app.data.IMU.(app.segment{s}).m(app.sacrum_trim_start: app.sacrum_trim_end,:);
            end
            % fix IMU time vector
            app.data.IMU.time = app.data.IMU.(app.segment{s}).time;
            %refresh vgrf vs sacrum plot
            PlotGRFSacrum(app)
            % get length(frames) of longest trial between FP and IMUs
            app.lim_IMU = max([length(app.data.IMU.time), length(app.time_down)]);
        end

        % Button pushed function: SyncGRFStartButton
        function SyncGRFStartButtonPushed(app, event)
            %get sacrum fieldname
            seg = char(app.segment(strcmpi(app.segment, 'sacrum')));
            signal = 'af'; %use af of sacrum to sync
            [lim,max_loc] = max([length(app.grf_down), length(app.data.IMU.(seg).(signal))]);
            [min_lim] = min([length(app.grf_down), length(app.data.IMU.(seg).(signal))]);
            data_to_align = NaN(lim, 2);
            
            % organize all sensors into a single matrix for corr_shift()
            data_to_align(1:length(app.grf_down), 1) = app.grf_down(:,3); %vgrf only
            data_to_align(1:length(app.data.IMU.(seg).(signal)), 2) = app.data.IMU.(seg).(signal)(:,2)*70; %filtered accel
            % keep just time vector of longest trial
            if max_loc == 2
                app.align_time = app.data.IMU.(seg).time;
            else
                app.align_time = app.time_down;
            end
            
            timex = app.UIAxes.XAxis.Limits;
            timex(timex < 0) = 0;
            timex(timex > min_lim/app.Fs) = min_lim/app.Fs;
            %update status label on bottom of gui
            app.StatusLabel.Text = ['Region boundaries: ', num2str(timex(1)) ' - ' num2str(timex(2)) ' seconds. Syncing signals...'];
            %figure out corresponding frames
            align_temp = app.align_time > min(timex) & app.align_time < max(timex);  % was app.time_down ...
            % beginning of spike
            align_start = find(app.align_time == min(app.align_time(align_temp)));
            % end of spike
            align_end = find(app.align_time == max(app.align_time(align_temp)));
            
            %gotta subtract offset from signals for improved corr_shift
            %performance
%             data_to_align_norm = data_to_align - 9.81*70; %body mass/weight
            
            % shift signals forwards/backwards in regards to first sensor in app.segment signal ("reference signal")
            [app.indexesIMU,app.AlignedIMUData] = corr_shift(data_to_align(:,1), data_to_align, align_start, align_end, 'f');
            app.AlignedIMUData = app.AlignedIMUData + 9.81*70; %body mass/weight
            app.intervalsIMU = [align_start align_end];
            
            time_shift = round(app.indexesIMU/app.Fs,3);
            
            shift_text = num2str(time_shift(2));
            app.StatusLabel.Text = ...
                ['Sensors shifted by ' shift_text ' sec relative to vGRF'];
            cla(app.UIAxes, 'reset')
            PlotSyncedData(app, app.align_time, app.AlignedIMUData, app.intervalsIMU, app.signal);
            app.UIAxes.Title.String = 'Synced Signals (Beginning)';
            legend(app.UIAxes, {'vGRF', 'Sacrum'});
            
            %confirm sync with user before we apply it to time vector
            opt = {'Yes, looks good!', 'No, I will try again.'};
            fig = uifigure('Position', [500, 500, 400, 200]);
            selection = uiconfirm(fig, 'Take a look at the plot. Do you want to apply this time shift?',...
                'Confirm Synchronization', 'Options', opt, 'CancelOption', 2);
            close(fig)
            
            if strcmp(selection, opt(1)) %selected YES
                %% apply shift to time
                for seg = 1:length(app.segment)
                    %apply sacrum shift from first hop to all sensors time
                    app.data.IMU.(app.segment{seg}).time_shifted = ...
                        app.data.IMU.(app.segment{seg}).time - app.indexesIMU(2)/app.Fs;
                end
                app.UIAxes.XLimMode = 'auto'; %return plot to full view
                app.GRFStartSynced = true; %confirm beginning is synced
                set(app.CheckGRFSyncBeg, 'visible', 'on'); %show green check mark
                set(app.SyncGRFEndButton, 'enable', 'on'); %enable sync end of file
                set(app.SyncGRFStartButton, 'enable', 'off'); %disable sync start
                set(app.CropBothSignals, 'enable', 'off'); %disable cropping after you like the stonk
            else
                PlotGRFSacrum(app)
            end
            
            
        end

        % Button pushed function: SyncGRFEndButton
        function SyncGRFEndButtonPushed(app, event)
            %check if beginning has been synced yet
            if ~app.GRFStartSynced
                errordlg('Beginning of signals must be synced first, then end.', 'Synchronization Error')
                return
            end
            
            lim = length(app.align_time);
            %select interval containing part of signal to base sync off of:
            timex = app.UIAxes.XAxis.Limits;
            timex(timex < 0) = 0;
            timex(timex > lim/app.Fs) = (lim-1)/app.Fs;
            % get corresponding frames
            %figure out corresponding frames
            align_temp = app.time_down > min(timex) & app.time_down < max(timex);
            % beginning of spike
            align_s = find(app.time_down == min(app.time_down(align_temp)));
            % end of spike
            align_e = find(app.time_down == max(app.time_down(align_temp)));
            
            %temporarily remove offset
%             app.AlignedIMUData = app.AlignedIMUData - 9.81*70; %body mass/weight
            
            %compare to first sensor, calcualte drift in clocks over duration of trial.
            [app.drifted, app.AlignedIMUData2] = corr_shift(app.AlignedIMUData(:,1), app.AlignedIMUData, align_s, align_e, 'f');
            app.intervalsIMU2 = [align_s align_e];
            
            %undo bodyweight offset
%             app.AlignedIMUData2 = app.AlignedIMUData2 + 9.81*70; %body mass/weight
            
            %calc time shift at end of trial
            time_shift = round(app.drifted/app.Fs,3);
            shift_text = num2str(time_shift(2));
            app.StatusLabel.Text = ...
                ['Sensors shifted by ' shift_text ' sec relative to vGRF'];
            PlotSyncedData(app, app.align_time, app.AlignedIMUData2, app.intervalsIMU2, app.signal);
            app.UIAxes.Title.String = 'Synced Signals (End)';
            legend(app.UIAxes, {'vGRF', 'Sacrum'})
            
            %confirm sync with user before we apply it to time vector
            opt = {'Yes, looks good!', 'No, I will try again.',};
            fig = uifigure('Position', [500, 500, 400, 200]);
            selection = uiconfirm(fig, 'Take a look at the plot. Do you want to apply this time shift?',...
                'Confirm Synchronization', 'Options', opt, 'CancelOption', 2);
            close(fig)
            if strcmp(selection, opt(1))
                %% apply shift to time
                for seg = 1:length(app.segment)
                    %calculate drift in IMU clock:
                    app.driftslope.(app.segment{seg}) = 1 - (app.drifted(2)/app.Fs)/ ...
                        ((app.intervalsIMU2(2)-app.intervalsIMU(2))/app.Fs); %BEST. Slope between 2 hops/swivels.
                    %apply drift correct from second hop
                    app.data.IMU.(app.segment{seg}).time_sd = app.driftslope.(app.segment{seg})* ...
                        app.data.IMU.(app.segment{seg}).time_shifted; %apply clock drift to already shifted time vector
                end
                %STOPPED HERE - prior good
                %apply shift to time and interpolate
                
                if app.GRFStartSynced
                    app.data.IMU.time = app.time_down; %is half the length of grf time
                    %interpolate so that IMU data fits to force time length
                    for s = 1:length(app.segment)
                        app.data.IMU.(app.segment{s}).a = ...
                            interp1(app.data.IMU.(app.segment{s}).time_sd,app.data.IMU.(app.segment{s}).a,app.time_down','nearest','extrap');  % script uses 'linear' instead of 'nearest'
                        app.data.IMU.(app.segment{s}).w = ...
                            interp1(app.data.IMU.(app.segment{s}).time_sd,app.data.IMU.(app.segment{s}).w,app.time_down','nearest','extrap');
                        app.data.IMU.(app.segment{s}).m = ...
                            interp1(app.data.IMU.(app.segment{s}).time_sd,app.data.IMU.(app.segment{s}).m,app.time_down','nearest','extrap');
                        
                        app.data.IMU.(app.segment{s}).time = app.data.IMU.time;
                    end
                    %plot
                    for s = 1:length(app.segment)
                        app.data.IMU.(app.segment{s}).af = filtfilt(app.b,app.a, app.data.IMU.(app.segment{s}).a);
                    end
                    cla(app.UIAxes, 'reset');
                    grid(app.UIAxes, 'on');
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes, app.data.time, app.data.force(:,3));
                    plot(app.UIAxes, app.data.IMU.time, app.data.IMU.Sacrum.af(:,2)*70);
                    title(app.UIAxes, 'Synced vGRF and Sacrum*70kg');
                    legend(app.UIAxes, {'vGRF', 'Sacrum*70kg'});
                    app.UIAxes.YLabel.String = 'Force [N]';
                    app.UIAxes.XLimMode = 'auto'; %return plot to full view
                    
                else
                    errordlg('Beginning of signals must be synced first, then end.', 'Synchronization Error')
                end
                
                set(app.CheckGRFSyncEnd, 'visible', 'on'); %show green check mark
                app.GRFEndSynced = true; %confirm beginning is synced
                set(app.SaveButton, 'enable', 'on') % enable save button
            else
                app.StatusLabel.Text = '';
                PlotSyncedData(app, app.align_time, app.AlignedIMUData, app.intervalsIMU, app.signal);
                app.UIAxes.XLimMode = 'auto'; %return plot to full view
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Remove unneeded IMU fields
            for seg = 1:length(app.segment)
                f = fieldnames(app.data.IMU.(app.segment{seg}));
                remove = f(~ismember(f,{'a','m','w','time'}));
                app.data.IMU.(app.segment{seg}) = rmfield(app.data.IMU.(app.segment{seg}),remove);
                
            end
            data = app.data;
            save(fullfile(app.loc, [app.file(1:end-4) '_synced.mat']), 'data')
            app.StatusLabel.Text = ['File saved: ' app.file(1:end-4) '_synced.mat'];
            app.StatusLabel.FontColor = [0.0196 0.4627 0.1059];
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.reset_data = true;
            LoadDataButtonPushed(app, event)
            app.reset_data = false;
        end

        % Button pushed function: SetDirectoryButton
        function SetDirectoryButtonPushed(app, event)
            app.directory = uigetdir('Select Directory Folder');
            app.DirectoryLabel.Text = app.directory;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SaveButton = uibutton(app.LeftPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.LoadDataButton = uibutton(app.LeftPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.SensorsTable = uitable(app.LeftPanel);
            app.SensorsTable.ColumnName = {'Sensors Found'};
            app.SensorsTable.RowName = {};
            app.SensorsTable.ColumnSortable = true;
            app.SensorsTable.Enable = 'off';
            app.SyncIMUstoeachotherPanel = uipanel(app.LeftPanel);
            app.SyncIMUstoeachotherPanel.Title = '1. Sync IMUs to each other';
            app.SyncIMUStartButton = uibutton(app.SyncIMUstoeachotherPanel, 'push');
            app.SyncIMUStartButton.ButtonPushedFcn = createCallbackFcn(app, @SyncIMUStartButtonPushed, true);
            app.SyncIMUStartButton.Enable = 'off';
            app.SyncIMUStartButton.Text = 'Sync Start';
            app.CheckIMUSyncBeg = uiimage(app.SyncIMUstoeachotherPanel);
            app.CheckIMUSyncBeg.Visible = 'off';
            app.CheckIMUSyncBeg.ImageSource = 'green_check.png';
            app.SyncIMUEndButton = uibutton(app.SyncIMUstoeachotherPanel, 'push');
            app.SyncIMUEndButton.ButtonPushedFcn = createCallbackFcn(app, @SyncIMUEndButtonPushed, true);
            app.SyncIMUEndButton.Enable = 'off';
            app.SyncIMUEndButton.Text = 'Sync End';
            app.CheckIMUSyncEnd = uiimage(app.SyncIMUstoeachotherPanel);
            app.CheckIMUSyncEnd.Visible = 'off';
            app.CheckIMUSyncEnd.ImageSource = 'green_check.png';
            app.CropIMUsButton = uibutton(app.LeftPanel, 'push');
            app.CropIMUsButton.ButtonPushedFcn = createCallbackFcn(app, @CropIMUsButtonPushed, true);
            app.CropIMUsButton.Enable = 'off';
            app.CropIMUsButton.Text = 'Crop IMUs';
            app.CropForcesButton = uibutton(app.LeftPanel, 'push');
            app.CropForcesButton.ButtonPushedFcn = createCallbackFcn(app, @CropForcesButtonPushed, true);
            app.CropForcesButton.Enable = 'off';
            app.CropForcesButton.Text = 'Crop Forces';
            app.PlotOptionsPanel = uipanel(app.LeftPanel);
            app.PlotOptionsPanel.Title = 'Plot Options';
            app.AxesButtonGroup = uibuttongroup(app.PlotOptionsPanel);
            app.AxesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AxesButtonGroupSelectionChanged, true);
            app.AxesButtonGroup.Title = 'Axes';
            app.XButton = uiradiobutton(app.AxesButtonGroup);
            app.XButton.Text = 'X';
            app.YButton = uiradiobutton(app.AxesButtonGroup);
            app.YButton.Text = 'Y';
            app.ZButton = uiradiobutton(app.AxesButtonGroup);
            app.ZButton.Text = 'Z';
            app.MagnitudeButton = uiradiobutton(app.AxesButtonGroup);
            app.MagnitudeButton.Text = 'Magnitude';
            app.MagnitudeButton.Value = true;
            app.ViewAngularVelocityButton = uibutton(app.PlotOptionsPanel, 'push');
            app.ViewAngularVelocityButton.ButtonPushedFcn = createCallbackFcn(app, @ViewAngularVelocityButtonPushed, true);
            app.ViewAngularVelocityButton.Enable = 'off';
            app.ViewAngularVelocityButton.Text = 'View Angular Velocity';
            app.ViewLinearAccelerationButton = uibutton(app.PlotOptionsPanel, 'push');
            app.ViewLinearAccelerationButton.ButtonPushedFcn = createCallbackFcn(app, @ViewLinearAccelerationButtonPushed, true);
            app.ViewLinearAccelerationButton.Enable = 'off';
            app.ViewLinearAccelerationButton.Text = 'View Linear Acceleration';
            app.SyncIMUstoForcesignalPanel = uipanel(app.LeftPanel);
            app.SyncIMUstoForcesignalPanel.Title = '2. Sync IMUs to Force signal';
            app.CheckGRFSyncEnd = uiimage(app.SyncIMUstoForcesignalPanel);
            app.CheckGRFSyncEnd.Visible = 'off';
            app.CheckGRFSyncEnd.ImageSource = 'green_check.png';
            app.CheckGRFSyncBeg = uiimage(app.SyncIMUstoForcesignalPanel);
            app.CheckGRFSyncBeg.Visible = 'off';
            app.CheckGRFSyncBeg.ImageSource = 'green_check.png';
            app.SyncGRFStartButton = uibutton(app.SyncIMUstoForcesignalPanel, 'push');
            app.SyncGRFStartButton.ButtonPushedFcn = createCallbackFcn(app, @SyncGRFStartButtonPushed, true);
            app.SyncGRFStartButton.Enable = 'off';
            app.SyncGRFStartButton.Text = 'Sync Start';
            app.SyncGRFEndButton = uibutton(app.SyncIMUstoForcesignalPanel, 'push');
            app.SyncGRFEndButton.ButtonPushedFcn = createCallbackFcn(app, @SyncGRFEndButtonPushed, true);
            app.SyncGRFEndButton.Enable = 'off';
            app.SyncGRFEndButton.Text = 'Sync End';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Text = 'Reset';
            app.SetDirectoryButton = uibutton(app.LeftPanel, 'push');
            app.SetDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SetDirectoryButtonPushed, true);
            app.SetDirectoryButton.Text = 'Set Directory';
            app.CropBothSignals = uibutton(app.LeftPanel, 'push');
            app.CropBothSignals.ButtonPushedFcn = createCallbackFcn(app, @CropBothSignalsButtonPushed, true);
            app.CropBothSignals.Enable = 'off';
            app.CropBothSignals.Text = 'Crop both signals after IMU sync';
            app.RightPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = {'To Sync (repeat for syncing IMUs to each other and then syncing IMUs to forces:'; ''; 'Step 1. Zoom into signal spikes at beginning of trial, then press [Sync Start]'; 'Step 2. Zoom into signal spikes at end of trial, then press [Sync End]'; ''; 'If syncing isn''t working, try cropping the signals by pressing [Crop IMUs] or [Crop Forces]'};
            app.StatusLabel = uilabel(app.RightPanel);
            app.StatusLabel.Text = '';
            app.FileLabel = uilabel(app.RightPanel);
            app.FileLabel.Text = '';
            app.DataViewerPanel = uipanel(app.RightPanel);
            app.DataViewerPanel.Title = 'Data Viewer';
            app.UIAxes = uiaxes(app.DataViewerPanel);
            title(app.UIAxes, 'Signals')
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, 'Y')
            app.DirectoryLabel = uilabel(app.RightPanel);
            app.DirectoryLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InSink

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end